classdef sketch_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OptimaizationMethodLabel     matlab.ui.control.Label
        OptimaizationMethodDropDown  matlab.ui.control.DropDown
        UITable                      matlab.ui.control.Table
        RunmindpButton               matlab.ui.control.Button
        AnsTable                     matlab.ui.control.Table
        UserLimitLabel               matlab.ui.control.Label
        ApplyLimitButton             matlab.ui.control.Button
        UserNumberEditFieldLabel     matlab.ui.control.Label
        UserNumberEditField          matlab.ui.control.NumericEditField
        ConfirmUserNumberButton      matlab.ui.control.Button
        ShowUserDataButton           matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        Location = "asd";
        UserDataTableApp % Description
    end
    
    properties (Access = public)
        randomUserTable = randomTable(3,[0 5 20 18 0.008 0.038 -0.014 0.4;
                0 10 30 25 0.015 0.056 -0.008 0.8]);
        userLimit = [0 5 20 18 0.008 0.038 -0.014 0.4;
                0 10 30 25 0.015 0.056 -0.008 0.8];
        value = 1;
        mindpData = 0;
    end
    
    methods (Access = public)
        
        function importdata(app,name)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: RunmindpButton
        function RunmindpButtonPushed(app, event)
            app.mindpData = mindp(app.value, app.randomUserTable);
            app.AnsTable.Data = app.mindpData;
            msgbox("ran");
        end

        % Button pushed function: ApplyLimitButton
        function ApplyLimitButtonPushed(app, event)
            app.userLimit = app.UITable.Data;  
            app.randomUserTable = randomTable(app.value, app.userLimit); %make new random user number table
            msgbox("Applied!");
        end

        % Value changed function: UserNumberEditField
        function UserNumberEditFieldValueChanged(app, event)
            app.value = app.UserNumberEditField.Value;
            
        end

        % Button pushed function: ConfirmUserNumberButton
        function ConfirmUserNumberButtonPushed(app, event)
            app.UITable.ColumnEditable = true(1,8); %grid become editable
            app.UITable.Data = app.userLimit; %print out the limit
            app.value = app.UserNumberEditField.Value; %local variable = printed out data
            msgbox("Changed");
             
             
        end

        % Button pushed function: ShowUserDataButton
        function ShowUserDataButtonPushed(app, event)
            app.UserDataTableApp = UserDataTable(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        filter = {'*.mat';'*.*'};
        var1 = app.userLimit;
        var2 = app.randomUserTable;
        var3 = app.value;
        var4 = app.mindpData;
       [file, path] = uiputfile(filter);

        save(fullfile(path, file), 'var1','var2','var3','var4');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
        filter = {'*.mat';'*.*'};

        [file, path] = uigetfile(filter);

        load(fullfile(path,file),'var1','var2','var3','var4');
        app.userLimit = var1;
        app.randomUserTable = var2;
        app.value = var3;
        app.mindpData = var4;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptimaizationMethodLabel = uilabel(app.UIFigure);
            app.OptimaizationMethodLabel.Text = 'Optimaization Method :';
            app.OptimaizationMethodDropDown = uidropdown(app.UIFigure);
            app.OptimaizationMethodDropDown.Items = {'Convergence', 'Option 2', 'Option 3', 'Option 4'};
            app.OptimaizationMethodDropDown.Value = 'Convergence';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'plow'; 'dlow'; 'phigh'; 'dhigh'; 'aplha1'; 'alpha2'; 'beta1'; 'beta2'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.RunmindpButton = uibutton(app.UIFigure, 'push');
            app.RunmindpButton.ButtonPushedFcn = createCallbackFcn(app, @RunmindpButtonPushed, true);
            app.RunmindpButton.Text = 'Run mindp';
            app.AnsTable = uitable(app.UIFigure);
            app.AnsTable.ColumnName = {'User1'; 'User2'; 'User3'; 'User 4'; 'User 5'};
            app.AnsTable.RowName = {'p;d'};
            app.UserLimitLabel = uilabel(app.UIFigure);
            app.UserLimitLabel.Text = 'User Limit :';
            app.ApplyLimitButton = uibutton(app.UIFigure, 'push');
            app.ApplyLimitButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLimitButtonPushed, true);
            app.ApplyLimitButton.Text = 'Apply Limit';
            app.UserNumberEditFieldLabel = uilabel(app.UIFigure);
            app.UserNumberEditFieldLabel.Text = 'User Number';
            app.UserNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.UserNumberEditField.Limits = [0 100];
            app.UserNumberEditField.ValueChangedFcn = createCallbackFcn(app, @UserNumberEditFieldValueChanged, true);
            app.UserNumberEditField.Value = 3;
            app.ConfirmUserNumberButton = uibutton(app.UIFigure, 'push');
            app.ConfirmUserNumberButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmUserNumberButtonPushed, true);
            app.ConfirmUserNumberButton.Text = 'Confirm';
            app.ShowUserDataButton = uibutton(app.UIFigure, 'push');
            app.ShowUserDataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowUserDataButtonPushed, true);
            app.ShowUserDataButton.Text = 'Show User Data';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sketch_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end